classdef AdaptiveThresholding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmenterUIFigure       matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        GarnetAnalyzerLabel     matlab.ui.control.Label
        LoadImageButton         matlab.ui.control.StateButton
        LoadMaskButton          matlab.ui.control.Button
        SaveMaskButton          matlab.ui.control.Button
        CellMaskCheckBox        matlab.ui.control.CheckBox
        Channel1CheckBox        matlab.ui.control.CheckBox
        Channel2CheckBox        matlab.ui.control.CheckBox
        Channel3CheckBox        matlab.ui.control.CheckBox
        Brightness              matlab.ui.control.Slider
        BrighterLabel           matlab.ui.control.Label
        DimmerLabel             matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        ZSliderLabel            matlab.ui.control.Label
        ZSlider                 matlab.ui.control.Slider
        TabGroup                matlab.ui.container.TabGroup
        ThresholdTab            matlab.ui.container.Tab
        SegmentSlider           matlab.ui.control.Slider
        LevelLabel2             matlab.ui.control.Label
        SegmentSigma            matlab.ui.control.Slider
        LevelLabel2_2           matlab.ui.control.Label
        LevelLabel2_25          matlab.ui.control.Label
        LevelLabel2_26          matlab.ui.control.Label
        LevelLabel2_30          matlab.ui.control.Label
        LevelLabel2_31          matlab.ui.control.Label
        SegmentDropDown         matlab.ui.control.DropDown
        SegmentThresholdButton  matlab.ui.control.Button
        LevelLabel2_24          matlab.ui.control.Label
        UIAxes_2                matlab.ui.control.UIAxes
    end

    properties (Access = private)
        C1 % channel 1
        C2 % channel 2
        C3 % channel 3
        voxel %voxel size
        M % binary image of the cell
        currentfile %current file
        flip %whether to flip the clipping plane
        brightness % scale factor for brightness
        currentpath % current path
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.C1 = [];
           app.C2 = [];
           app.C3 = [];
           app.M = [];
           app.flip = 1;
           app.brightness = 1;
           addpath('./Utilities');
        end

        % Callback function
        function GenerateSeedButtonPushed(app, event)
            if(isempty(app.C1))
                msgbox('Load image first');
            else
                app.M = makeseed(app.C1,app.SegmentRadius.Value);
                app.CellMaskCheckBox.Value = 1;
                app.showImage();
            end
        end

        % Value changed function: Brightness
        function BrightnessValueChanged(app, event)
            app.brightness = app.Brightness.Value;
            app.showImage();
        end

        % Callback function
        function CropButtonPushed(app, event)
            plane = makeplane3(app.C1,app.Theta.Value,app.Phi.Value,app.SliderR.Value,app.flip);
            app.C1 = app.C1.*plane;
            app.C2 = app.C2.*plane;
            app.C3 = app.C3.*plane;
        end

        % Callback function
        function FlipPlaneButtonPushed(app, event)
            app.flip = -1*app.flip;
            app.showImage();
        end

        % Callback function
        function PhiValueChanged(app, event)
            app.showImage();
        end

        % Callback function
        function ShowPlaneCheckBoxValueChanged(app, event)
            app.showImage();          
        end

        % Callback function
        function ReButtonPushed(app, event)
            if(isempty(app.M))
                msgbox('Calculate cell mask first');
            else
                app.M = refinemask(app.M,app.Amount.Value);
                app.showImage();
            end
        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)
            if(isempty(app.M))
                msgbox('Calculate cell mask first');
            else
                savemask(app.M,strcat(app.currentpath,'/mask_',app.currentfile));
            end
        end

        % Callback function
        function ApplyTransformButtonPushed(app, event)
            if(isempty(app.M))
                msgbox('Calculate cell mask first');
            else
                app.M = separatecells(app.M,app.SegmentRadius_2.Value,app.voxel);
                app.showImage();
            end
        end

        % Callback function
        function SliderRValueChanged(app, event)
            app.showImage();     
        end

        % Callback function
        function SliderZValueChanged(app, event)
            app.showImage();   
        end

        % Callback function
        function ThetaValueChanged(app, event)
            app.showImage();
        end

        % Callback function
        function View3DButtonPushed(app, event)
            show3d(app.M);
        end

        % Callback function
        function WatershedButtonPushed(app, event)
             if(isempty(app.M))
                msgbox('Need the generate seed first');
            else
                if(app.SegmentDropDown_2.Value == 'Channel 1')
                    app.M = watershedcell(app.C1,app.M,app.SegmentSigma_2.Value);
                elseif(app.SegmentDropDown_2.Value == 'Channel 2')
                    app.M = watershedcell(app.C2,app.M,app.SegmentSigma_2.Value);
                else
                    app.M =  watershedcell(app.C3,app.M,app.SegmentSigma_2.Value);
                end
                app.showImage();
            end
        end

        % Button pushed function: SegmentThresholdButton
        function cellThresholdButtonPushed(app, event)
            if(isempty(app.C1))
                msgbox('Load image first');
            else
                if(app.SegmentDropDown.Value == 'Channel 1')
                    app.M = adpativethresholdcell(app.C1,app.SegmentSlider.Value,app.SegmentSigma.Value,app.Slope.Value);
                elseif(app.SegmentDropDown.Value == 'Channel 2')
                    app.M = adpativethresholdcell(app.C2,app.SegmentSlider.Value,app.SegmentSigma.Value,app.Slope.Value);
                else
                    app.M = adpativethresholdcell(app.C3,app.SegmentSlider.Value,app.SegmentSigma.Value,app.Slope.Value);
                end
                app.CellMaskCheckBox.Value = 1;
                app.showImage();
            end
        end

        % Value changed function: LoadImageButton
        function loadImageButtonPushed2(app, event)
            [filename,path] = uigetfile('*.tif');
            if filename ~= 0
                addpath(path);
                app.currentfile = filename;
                app.currentpath = path;
                [I,app.voxel] = loadimage(app.currentfile);
                app.C1 = I(:,:,:,1);
                if size(I,4) > 1
                    app.C2 = I(:,:,:,2);
                else
                    app.C2 = I(:,:,:,1).*0;
                end
                if size(I,4) > 2
                    app.C3 = I(:,:,:,3);
                else
                    app.C3 = I(:,:,:,1).*0;
                end
                app.ZSlider.Limits = [1 size(app.C1,3)];
                app.CellMaskCheckBox.Value = 0;
                app.M = [];
                app.showImage();
            end
            app.LoadImageButton.Value = 0;
        end

        % Button pushed function: LoadMaskButton
        function loadMaskButtonPushed3(app, event)
            if(isempty(app.C1))
                msgbox('Load image first');
            else
                [filename,path] = uigetfile('*.tif');
                if filename ~= 0
                    addpath(path);
                    app.M = loadmask(filename);
                    app.CellMaskCheckBox.Value = 1;
                    app.showImage();
                end
            end
        end

        % Callback function: CellMaskCheckBox, Channel1CheckBox, 
        % Channel2CheckBox, Channel3CheckBox, ZSlider, ZSlider
        function showImage(app, event)
            z = round(app.ZSlider.Value);
            if(app.Channel1CheckBox.Value == 1)
                G = app.C1(:,:,z)*app.brightness;
            else
                G = app.C1(:,:,1).*0;
            end
            if(app.Channel2CheckBox.Value == 1)
                B = app.C2(:,:,z)*app.brightness;
            else
                B = app.C2(:,:,1).*0;
            end
            if(app.Channel3CheckBox.Value == 1)
                R = app.C3(:,:,z)*app.brightness;
            else
                R = app.C3(:,:,1).*0;
            end
            if(app.CellMaskCheckBox.Value == 1)
                if(isempty(app.M))
                    msgbox('Calculate cell mask first');
                    app.CellMaskCheckBox.Value = 0;
                else
                    R = max(R,uint16(bwperim(app.M(:,:,z))*65535));
                    G = max(G,uint16(bwperim(app.M(:,:,z))*65535));
                    B = max(B,uint16(bwperim(app.M(:,:,z))*65535));
                end
            end
            
            %{
            if(app.ShowPlaneCheckBox.Value == 1)
                plane = makeplane2(app.C1,z,app.Theta.Value,app.Phi.Value,app.SliderR.Value,app.flip);
                R = R + plane;
                G = G + plane;
                B = B + plane;
            end
            %}
            %display magenta
            B = B+R;
            image(app.UIAxes,cat(3,R,G,B));
        end

        % Callback function
        function ErodeMaskButtonPushed(app, event)
            if(isempty(app.M))
                    msgbox('Calculate cell mask first');
            else
                if app.ApplyIn3DCheckBox.Value == 1
                    SE = strel('cube',app.ErosionAmountSpinner.Value*2);
                else
                    SE = strel('diamond',app.ErosionAmountSpinner.Value);
                end
                app.M = imerode(app.M,SE);
            end
            app.showImage();
        end

        % Callback function
        function DilateMaskButtonPushed(app, event)
            if(isempty(app.M))
                    msgbox('Calculate cell mask first');
            else
                if app.ApplyIn3DCheckBox.Value == 1
                    SE = strel('cube',app.DilationAmountSpinner.Value*2);
                else
                    SE = strel('diamond',app.DilationAmountSpinner.Value);
                end
                app.M = imdilate(app.M,SE);
            end
            app.showImage();
        end

        % Value changed function: SegmentSlider
        function SegmentSliderValueChanged(app, event)
            value = app.SegmentSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmenterUIFigure = uifigure('Visible', 'off');
            app.SegmenterUIFigure.Name = 'Segmenter';
            app.Panel = uipanel(app.SegmenterUIFigure);
            app.GarnetAnalyzerLabel = uilabel(app.Panel);
            app.GarnetAnalyzerLabel.Text = 'Garnet Analyzer';
            app.LoadImageButton = uibutton(app.Panel, 'state');
            app.LoadImageButton.ValueChangedFcn = createCallbackFcn(app, @loadImageButtonPushed2, true);
            app.LoadImageButton.Text = 'Load Image';
            app.LoadMaskButton = uibutton(app.Panel, 'push');
            app.LoadMaskButton.ButtonPushedFcn = createCallbackFcn(app, @loadMaskButtonPushed3, true);
            app.LoadMaskButton.Text = 'Load Mask';
            app.SaveMaskButton = uibutton(app.Panel, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Text = 'Save Mask';
            app.CellMaskCheckBox = uicheckbox(app.Panel);
            app.CellMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.CellMaskCheckBox.Text = 'Cell Mask';
            app.Channel1CheckBox = uicheckbox(app.Panel);
            app.Channel1CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Channel1CheckBox.Text = 'Channel 1';
            app.Channel1CheckBox.Value = true;
            app.Channel2CheckBox = uicheckbox(app.Panel);
            app.Channel2CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Channel2CheckBox.Text = 'Channel 2';
            app.Channel2CheckBox.Value = true;
            app.Channel3CheckBox = uicheckbox(app.Panel);
            app.Channel3CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Channel3CheckBox.Text = 'Channel 3';
            app.Channel3CheckBox.Value = true;
            app.Brightness = uislider(app.Panel);
            app.Brightness.MajorTicks = [];
            app.Brightness.MajorTickLabels = {};
            app.Brightness.ValueChangedFcn = createCallbackFcn(app, @BrightnessValueChanged, true);
            app.Brightness.MinorTicks = [];
            app.Brightness.Value = 1;
            app.BrighterLabel = uilabel(app.Panel);
            app.BrighterLabel.Text = {'Brighter'; ''};
            app.DimmerLabel = uilabel(app.Panel);
            app.DimmerLabel.Text = {'Dimmer'; ''};
            app.UIAxes = uiaxes(app.SegmenterUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ZSliderLabel = uilabel(app.SegmenterUIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.SegmenterUIFigure);
            app.ZSlider.MajorTicks = [];
            app.ZSlider.MajorTickLabels = {};
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @showImage, true);
            app.ZSlider.MinorTicks = [];
            app.TabGroup = uitabgroup(app.SegmenterUIFigure);
            app.ThresholdTab = uitab(app.TabGroup);
            app.ThresholdTab.Title = 'Threshold';
            app.SegmentSlider = uislider(app.ThresholdTab);
            app.SegmentSlider.Limits = [0 3];
            app.SegmentSlider.MajorTicks = [];
            app.SegmentSlider.MajorTickLabels = {};
            app.SegmentSlider.ValueChangedFcn = createCallbackFcn(app, @SegmentSliderValueChanged, true);
            app.SegmentSlider.MinorTicks = [];
            app.SegmentSlider.Value = 1.5;
            app.LevelLabel2 = uilabel(app.ThresholdTab);
            app.LevelLabel2.Text = 'Threshold Level';
            app.SegmentSigma = uislider(app.ThresholdTab);
            app.SegmentSigma.Limits = [0 10];
            app.SegmentSigma.MajorTicks = [];
            app.SegmentSigma.MajorTickLabels = {};
            app.SegmentSigma.MinorTicks = [];
            app.SegmentSigma.Value = 5;
            app.LevelLabel2_2 = uilabel(app.ThresholdTab);
            app.LevelLabel2_2.Text = 'Gaussian Blur';
            app.SegmentDropDown = uidropdown(app.ThresholdTab);
            app.SegmentDropDown.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.SegmentDropDown.Value = 'Channel 1';
            app.SegmentThresholdButton = uibutton(app.ThresholdTab, 'push');
            app.SegmentThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @cellThresholdButtonPushed, true);
            app.SegmentThresholdButton.Text = 'Threshold';
            app.LevelLabel2_24 = uilabel(app.ThresholdTab);
            app.LevelLabel2_24.Text = 'Threshold Channel';
            app.LevelLabel2_25 = uilabel(app.ThresholdTab);
            app.LevelLabel2_25.Text = 'Lower';
            app.LevelLabel2_26 = uilabel(app.ThresholdTab);
            app.LevelLabel2_26.Text = 'Higher';
            app.LevelLabel2_30 = uilabel(app.ThresholdTab);
            app.LevelLabel2_30.Text = 'Less';
            app.LevelLabel2_31 = uilabel(app.ThresholdTab);
            app.LevelLabel2_31.Text = 'More';
            app.UIAxes_2 = uiaxes(app.SegmenterUIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.SegmenterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdaptiveThresholding

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegmenterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmenterUIFigure)
        end
    end
end