classdef Segmenter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmenterUIFigure           matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        GarnetSegmenterLabel        matlab.ui.control.Label
        LoadImageButton             matlab.ui.control.StateButton
        LoadMaskButton              matlab.ui.control.Button
        SaveMaskButton              matlab.ui.control.Button
        CellMaskCheckBox            matlab.ui.control.CheckBox
        Channel1CheckBox            matlab.ui.control.CheckBox
        Channel2CheckBox            matlab.ui.control.CheckBox
        Channel3CheckBox            matlab.ui.control.CheckBox
        Brightness                  matlab.ui.control.Slider
        BrighterLabel               matlab.ui.control.Label
        DimmerLabel                 matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        ZSliderLabel                matlab.ui.control.Label
        ZSlider                     matlab.ui.control.Slider
        TabGroup                    matlab.ui.container.TabGroup
        CropTab                     matlab.ui.container.Tab
        ShowPlaneCheckBox           matlab.ui.control.CheckBox
        FlipPlaneButton             matlab.ui.control.Button
        Theta                       matlab.ui.control.Slider
        SliderR                     matlab.ui.control.Slider
        Phi                         matlab.ui.control.Slider
        LevelLabel2_13              matlab.ui.control.Label
        LevelLabel2_16              matlab.ui.control.Label
        LevelLabel2_18              matlab.ui.control.Label
        LevelLabel2_8               matlab.ui.control.Label
        LevelLabel2_5               matlab.ui.control.Label
        LevelLabel2_20              matlab.ui.control.Label
        LevelLabel2_21              matlab.ui.control.Label
        LevelLabel2_22              matlab.ui.control.Label
        LevelLabel2_23              matlab.ui.control.Label
        LevelLabel2_17              matlab.ui.control.Label
        LevelLabel2_15              matlab.ui.control.Label
        LevelLabel2_19              matlab.ui.control.Label
        CropButton                  matlab.ui.control.Button
        ThresholdTab                matlab.ui.container.Tab
        SegmentSlider               matlab.ui.control.Slider
        LevelLabel2                 matlab.ui.control.Label
        Slope                       matlab.ui.control.Slider
        LevelLabel2_9               matlab.ui.control.Label
        SegmentSigma                matlab.ui.control.Slider
        LevelLabel2_2               matlab.ui.control.Label
        SegmentDropDown             matlab.ui.control.DropDown
        SegmentThresholdButton      matlab.ui.control.Button
        LevelLabel2_24              matlab.ui.control.Label
        LevelLabel2_25              matlab.ui.control.Label
        LevelLabel2_26              matlab.ui.control.Label
        LevelLabel2_27              matlab.ui.control.Label
        LevelLabel2_28              matlab.ui.control.Label
        LevelLabel2_29              matlab.ui.control.Label
        LevelLabel2_30              matlab.ui.control.Label
        LevelLabel2_31              matlab.ui.control.Label
        MaxareaofcellinpixelshoveroverformoredetailsTextAreaLabel  matlab.ui.control.Label
        MaxareaofcellinpixelshoveroverformoredetailsTextArea  matlab.ui.control.TextArea
        WatershedTab                matlab.ui.container.Tab
        GenerateSeedButton          matlab.ui.control.Button
        SegmentRadius               matlab.ui.control.Slider
        LevelLabel2_3               matlab.ui.control.Label
        WatershedButton             matlab.ui.control.Button
        LevelLabel2_36              matlab.ui.control.Label
        LevelLabel2_37              matlab.ui.control.Label
        LevelLabel2_38              matlab.ui.control.Label
        SegmentDropDown_2           matlab.ui.control.DropDown
        LevelLabel2_39              matlab.ui.control.Label
        SegmentSigma_2              matlab.ui.control.Slider
        LevelLabel2_40              matlab.ui.control.Label
        LevelLabel2_41              matlab.ui.control.Label
        LevelLabel2_42              matlab.ui.control.Label
        RefineTab                   matlab.ui.container.Tab
        View3DButton                matlab.ui.control.Button
        ErodeMaskButton             matlab.ui.control.Button
        DilateMaskButton            matlab.ui.control.Button
        ApplyTransformButton        matlab.ui.control.Button
        ErosionAmountSpinnerLabel   matlab.ui.control.Label
        ErosionAmountSpinner        matlab.ui.control.Spinner
        DilationAmountSpinnerLabel  matlab.ui.control.Label
        DilationAmountSpinner       matlab.ui.control.Spinner
        LevelLabel2_32              matlab.ui.control.Label
        SegmentRadius_2             matlab.ui.control.Slider
        LevelLabel2_33              matlab.ui.control.Label
        LevelLabel2_34              matlab.ui.control.Label
        LevelLabel2_35              matlab.ui.control.Label
        ApplyIn3DCheckBox           matlab.ui.control.CheckBox
    end

    properties (Access = private)
        C1 % channel 1
        C2 % channel 2
        C3 % channel 3
        voxel %voxel size
        M % binary image of the cell
        currentfile %current file
        flip %whether to flip the clipping plane
        brightness % scale factor for brightness
        currentpath % current path
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.C1 = [];
           app.C2 = [];
           app.C3 = [];
           app.M = [];
           app.flip = 1;
           app.brightness = 1;
           addpath('./Utilities');
        end

        % Button pushed function: GenerateSeedButton
        function GenerateSeedButtonPushed(app, event)
            if(isempty(app.C1))
                msgbox('Load image first');
            else
                app.M = makeseed(app.C1,app.SegmentRadius.Value);
                app.CellMaskCheckBox.Value = 1;
                app.showImage();
            end
        end

        % Value changed function: Brightness
        function BrightnessValueChanged(app, event)
            app.brightness = app.Brightness.Value;
            app.showImage();
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            plane = makeplane3(app.C1,app.Theta.Value,app.Phi.Value,app.SliderR.Value,app.flip);
            app.C1 = app.C1.*plane;
            app.C2 = app.C2.*plane;
            app.C3 = app.C3.*plane;
        end

        % Button pushed function: FlipPlaneButton
        function FlipPlaneButtonPushed(app, event)
            app.flip = -1*app.flip;
            app.showImage();
        end

        % Value changed function: Phi
        function PhiValueChanged(app, event)
            app.showImage();
        end

        % Value changed function: ShowPlaneCheckBox
        function ShowPlaneCheckBoxValueChanged(app, event)
            app.showImage();          
        end

        % Callback function
        function ReButtonPushed(app, event)
            if(isempty(app.M))
                msgbox('Calculate cell mask first');
            else
                app.M = refinemask(app.M,app.Amount.Value);
                app.showImage();
            end
        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)
            if(isempty(app.M))
                msgbox('Calculate cell mask first');
            else
                savemask(app.M,strcat(app.currentpath,'/mask_',app.currentfile));
            end
        end

        % Button pushed function: ApplyTransformButton
        function ApplyTransformButtonPushed(app, event)
            if(isempty(app.M))
                msgbox('Calculate cell mask first');
            else
                app.M = separatecells(app.M,app.SegmentRadius_2.Value,app.voxel);
                app.showImage();
            end
        end

        % Value changed function: SliderR
        function SliderRValueChanged(app, event)
            app.showImage();     
        end

        % Callback function
        function SliderZValueChanged(app, event)
            app.showImage();   
        end

        % Value changed function: Theta
        function ThetaValueChanged(app, event)
            app.showImage();
        end

        % Button pushed function: View3DButton
        function View3DButtonPushed(app, event)
            show3d(app.M);
        end

        % Button pushed function: WatershedButton
        function WatershedButtonPushed(app, event)
             if(isempty(app.M))
                msgbox('Need the generate seed first');
            else
                if(app.SegmentDropDown_2.Value == 'Channel 1')
                    app.M = watershedcell(app.C1,app.M,app.SegmentSigma_2.Value);
                elseif(app.SegmentDropDown_2.Value == 'Channel 2')
                    app.M = watershedcell(app.C2,app.M,app.SegmentSigma_2.Value);
                else
                    app.M =  watershedcell(app.C3,app.M,app.SegmentSigma_2.Value);
                end
                app.showImage();
            end
        end

        % Button pushed function: SegmentThresholdButton
        function cellThresholdButtonPushed(app, event)
            if(isempty(app.C1))
                msgbox('Load image first');
            else
                try
                    max_cell_area = str2double(app.MaxareaofcellinpixelshoveroverformoredetailsTextArea.Value{1});
                catch
                    max_cell_area = 100000;
                    warning('Unable to read input to Maximum Cell Area field. Running with maximum of 100000')
                end
                
                if(app.SegmentDropDown.Value == 'Channel 1')
                    app.M = thresholdcell(app.C1,app.SegmentSlider.Value,app.SegmentSigma.Value,app.Slope.Value,max_cell_area);
                elseif(app.SegmentDropDown.Value == 'Channel 2')
                    app.M = thresholdcell(app.C2,app.SegmentSlider.Value,app.SegmentSigma.Value,app.Slope.Value,max_cell_area);
                else
                    app.M = thresholdcell(app.C3,app.SegmentSlider.Value,app.SegmentSigma.Value,app.Slope.Value,max_cell_area);
                end
                app.CellMaskCheckBox.Value = 1;
                app.showImage();
            end
        end

        % Value changed function: LoadImageButton
        function loadImageButtonPushed2(app, event)
            [filename,path] = uigetfile('*.tif');
            if filename ~= 0
                addpath(path);
                app.currentfile = filename;
                app.currentpath = path;
                [I,app.voxel] = loadimage(app.currentfile);
                app.C1 = I(:,:,:,1);
                if size(I,4) > 1
                    app.C2 = I(:,:,:,2);
                else
                    app.C2 = I(:,:,:,1).*0;
                end
                if size(I,4) > 2
                    app.C3 = I(:,:,:,3);
                else
                    app.C3 = I(:,:,:,1).*0;
                end
                app.ZSlider.Limits = [1 size(app.C1,3)];
                app.CellMaskCheckBox.Value = 0;
                app.M = [];
                app.showImage();
            end
            app.LoadImageButton.Value = 0;
        end

        % Button pushed function: LoadMaskButton
        function loadMaskButtonPushed3(app, event)
            if(isempty(app.C1))
                msgbox('Load image first');
            else
                [filename,path] = uigetfile('*.tif');
                if filename ~= 0
                    addpath(path);
                    app.M = loadmask(filename);
                    app.CellMaskCheckBox.Value = 1;
                    app.showImage();
                end
            end
        end

        % Callback function: CellMaskCheckBox, Channel1CheckBox, 
        % Channel2CheckBox, Channel3CheckBox, ZSlider, ZSlider
        function showImage(app, event)
            z = round(app.ZSlider.Value);
            if(app.Channel1CheckBox.Value == 1)
                G = app.C1(:,:,z)*app.brightness;
            else
                G = app.C1(:,:,1).*0;
            end
            if(app.Channel2CheckBox.Value == 1)
                B = app.C2(:,:,z)*app.brightness;
            else
                B = app.C2(:,:,1).*0;
            end
            if(app.Channel3CheckBox.Value == 1)
                R = app.C3(:,:,z)*app.brightness;
            else
                R = app.C3(:,:,1).*0;
            end
            if(app.CellMaskCheckBox.Value == 1)
                if(isempty(app.M))
                    msgbox('Calculate cell mask first');
                    app.CellMaskCheckBox.Value = 0;
                else
                    R = max(R,uint16(bwperim(app.M(:,:,z))*65535));
                    G = max(G,uint16(bwperim(app.M(:,:,z))*65535));
                    B = max(B,uint16(bwperim(app.M(:,:,z))*65535));
                end
            end
            if(app.ShowPlaneCheckBox.Value == 1)
                plane = makeplane2(app.C1,z,app.Theta.Value,app.Phi.Value,app.SliderR.Value,app.flip);
                R = R + plane;
                G = G + plane;
                B = B + plane;
            end
            %display magenta
            B = B+R;
            image(app.UIAxes,cat(3,R,G,B));
        end

        % Button pushed function: ErodeMaskButton
        function ErodeMaskButtonPushed(app, event)
            if(isempty(app.M))
                    msgbox('Calculate cell mask first');
            else
                if app.ApplyIn3DCheckBox.Value == 1
                    SE = strel('cube',app.ErosionAmountSpinner.Value*2);
                else
                    SE = strel('diamond',app.ErosionAmountSpinner.Value);
                end
                app.M = imerode(app.M,SE);
            end
            app.showImage();
        end

        % Button pushed function: DilateMaskButton
        function DilateMaskButtonPushed(app, event)
            if(isempty(app.M))
                    msgbox('Calculate cell mask first');
            else
                if app.ApplyIn3DCheckBox.Value == 1
                    SE = strel('cube',app.DilationAmountSpinner.Value*2);
                else
                    SE = strel('diamond',app.DilationAmountSpinner.Value);
                end
                app.M = imdilate(app.M,SE);
            end
            app.showImage();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmenterUIFigure = uifigure;
            app.SegmenterUIFigure.Name = 'Segmenter';
            app.Panel = uipanel(app.SegmenterUIFigure);
            app.GarnetSegmenterLabel = uilabel(app.Panel);
            app.GarnetSegmenterLabel.Text = 'Garnet Segmenter';
            app.LoadImageButton = uibutton(app.Panel, 'state');
            app.LoadImageButton.ValueChangedFcn = createCallbackFcn(app, @loadImageButtonPushed2, true);
            app.LoadImageButton.Text = 'Load Image';
            app.LoadMaskButton = uibutton(app.Panel, 'push');
            app.LoadMaskButton.ButtonPushedFcn = createCallbackFcn(app, @loadMaskButtonPushed3, true);
            app.LoadMaskButton.Text = 'Load Mask';
            app.SaveMaskButton = uibutton(app.Panel, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Text = 'Save Mask';
            app.CellMaskCheckBox = uicheckbox(app.Panel);
            app.CellMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.CellMaskCheckBox.Text = 'Cell Mask';
            app.Channel1CheckBox = uicheckbox(app.Panel);
            app.Channel1CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Channel1CheckBox.Text = 'Channel 1';
            app.Channel1CheckBox.Value = true;
            app.Channel2CheckBox = uicheckbox(app.Panel);
            app.Channel2CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Channel2CheckBox.Text = 'Channel 2';
            app.Channel2CheckBox.Value = true;
            app.Channel3CheckBox = uicheckbox(app.Panel);
            app.Channel3CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Channel3CheckBox.Text = 'Channel 3';
            app.Channel3CheckBox.Value = true;
            app.Brightness = uislider(app.Panel);
            app.Brightness.Limits = [0 10];
            app.Brightness.MajorTicks = [];
            app.Brightness.MajorTickLabels = {};
            app.Brightness.ValueChangedFcn = createCallbackFcn(app, @BrightnessValueChanged, true);
            app.Brightness.MinorTicks = [];
            app.Brightness.Value = 1;
            app.BrighterLabel = uilabel(app.Panel);
            app.BrighterLabel.Text = {'Brighter'; ''};
            app.DimmerLabel = uilabel(app.Panel);
            app.DimmerLabel.Text = {'Dimmer'; ''};
            app.UIAxes = uiaxes(app.SegmenterUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ZSliderLabel = uilabel(app.SegmenterUIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.SegmenterUIFigure);
            app.ZSlider.MajorTicks = [];
            app.ZSlider.MajorTickLabels = {};
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @showImage, true);
            app.ZSlider.MinorTicks = [];
            app.TabGroup = uitabgroup(app.SegmenterUIFigure);
            app.CropTab = uitab(app.TabGroup);
            app.CropTab.Title = 'Crop';
            app.ShowPlaneCheckBox = uicheckbox(app.CropTab);
            app.ShowPlaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPlaneCheckBoxValueChanged, true);
            app.ShowPlaneCheckBox.Text = 'Show Plane';
            app.FlipPlaneButton = uibutton(app.CropTab, 'push');
            app.FlipPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @FlipPlaneButtonPushed, true);
            app.FlipPlaneButton.Text = 'Flip Plane';
            app.Theta = uislider(app.CropTab);
            app.Theta.Limits = [0 3.1415];
            app.Theta.MajorTicks = [];
            app.Theta.MajorTickLabels = {};
            app.Theta.ValueChangedFcn = createCallbackFcn(app, @ThetaValueChanged, true);
            app.Theta.MinorTicks = [];
            app.Theta.Value = 1.5707;
            app.SliderR = uislider(app.CropTab);
            app.SliderR.Limits = [-200 200];
            app.SliderR.MajorTicks = [];
            app.SliderR.MajorTickLabels = {};
            app.SliderR.ValueChangedFcn = createCallbackFcn(app, @SliderRValueChanged, true);
            app.SliderR.MinorTicks = [];
            app.SliderR.Value = 1;
            app.Phi = uislider(app.CropTab);
            app.Phi.Limits = [0 6.283];
            app.Phi.MajorTicks = [];
            app.Phi.MajorTickLabels = {};
            app.Phi.ValueChangedFcn = createCallbackFcn(app, @PhiValueChanged, true);
            app.Phi.MinorTicks = [];
            app.Phi.Value = 3.1415;
            app.LevelLabel2_13 = uilabel(app.CropTab);
            app.LevelLabel2_13.Text = '0°';
            app.LevelLabel2_16 = uilabel(app.CropTab);
            app.LevelLabel2_16.Text = '-180°';
            app.LevelLabel2_18 = uilabel(app.CropTab);
            app.LevelLabel2_18.Text = '180°';
            app.LevelLabel2_8 = uilabel(app.CropTab);
            app.LevelLabel2_8.Text = 'Distance From Center';
            app.LevelLabel2_5 = uilabel(app.CropTab);
            app.LevelLabel2_5.Text = 'Angle In Plane';
            app.LevelLabel2_20 = uilabel(app.CropTab);
            app.LevelLabel2_20.Text = 'Angle Out Of Plane';
            app.LevelLabel2_21 = uilabel(app.CropTab);
            app.LevelLabel2_21.Text = 'Close';
            app.LevelLabel2_22 = uilabel(app.CropTab);
            app.LevelLabel2_22.Text = 'Far';
            app.LevelLabel2_23 = uilabel(app.CropTab);
            app.LevelLabel2_23.Text = 'Far';
            app.LevelLabel2_17 = uilabel(app.CropTab);
            app.LevelLabel2_17.Text = '-180°';
            app.LevelLabel2_15 = uilabel(app.CropTab);
            app.LevelLabel2_15.Text = '0°';
            app.LevelLabel2_19 = uilabel(app.CropTab);
            app.LevelLabel2_19.Text = '180°';
            app.CropButton = uibutton(app.CropTab, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.ThresholdTab = uitab(app.TabGroup);
            app.ThresholdTab.Title = 'Threshold';
            app.SegmentSlider = uislider(app.ThresholdTab);
            app.SegmentSlider.Limits = [0 3];
            app.SegmentSlider.MajorTicks = [];
            app.SegmentSlider.MajorTickLabels = {};
            app.SegmentSlider.MinorTicks = [];
            app.SegmentSlider.Value = 1.5;
            app.LevelLabel2 = uilabel(app.ThresholdTab);
            app.LevelLabel2.Text = 'Threshold Level';
            app.Slope = uislider(app.ThresholdTab);
            app.Slope.Limits = [-0.05 0.05];
            app.Slope.MajorTicks = [];
            app.Slope.MajorTickLabels = {};
            app.Slope.MinorTicks = [];
            app.LevelLabel2_9 = uilabel(app.ThresholdTab);
            app.LevelLabel2_9.Text = 'Threshold Slope';
            app.SegmentSigma = uislider(app.ThresholdTab);
            app.SegmentSigma.Limits = [0 10];
            app.SegmentSigma.MajorTicks = [];
            app.SegmentSigma.MajorTickLabels = {};
            app.SegmentSigma.MinorTicks = [];
            app.SegmentSigma.Value = 5;
            app.LevelLabel2_2 = uilabel(app.ThresholdTab);
            app.LevelLabel2_2.Text = 'Gaussian Blur';
            app.SegmentDropDown = uidropdown(app.ThresholdTab);
            app.SegmentDropDown.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.SegmentDropDown.Value = 'Channel 1';
            app.SegmentThresholdButton = uibutton(app.ThresholdTab, 'push');
            app.SegmentThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @cellThresholdButtonPushed, true);
            app.SegmentThresholdButton.Text = 'Threshold';
            app.LevelLabel2_24 = uilabel(app.ThresholdTab);
            app.LevelLabel2_24.Text = 'Threshold Channel';
            app.LevelLabel2_25 = uilabel(app.ThresholdTab);
            app.LevelLabel2_25.Text = 'Lower';
            app.LevelLabel2_26 = uilabel(app.ThresholdTab);
            app.LevelLabel2_26.Text = 'Higher';
            app.LevelLabel2_27 = uilabel(app.ThresholdTab);
            app.LevelLabel2_27.Text = 'None';
            app.LevelLabel2_28 = uilabel(app.ThresholdTab);
            app.LevelLabel2_28.Text = 'Lower At Bottom';
            app.LevelLabel2_29 = uilabel(app.ThresholdTab);
            app.LevelLabel2_29.Text = 'Lower At Top';
            app.LevelLabel2_30 = uilabel(app.ThresholdTab);
            app.LevelLabel2_30.Text = 'Less';
            app.LevelLabel2_31 = uilabel(app.ThresholdTab);
            app.LevelLabel2_31.Text = 'More';
            app.MaxareaofcellinpixelshoveroverformoredetailsTextAreaLabel = uilabel(app.ThresholdTab);
            app.MaxareaofcellinpixelshoveroverformoredetailsTextAreaLabel.Text = {'Max area'; 'of cell in pixels²'; '(hoverover for '; 'more details)'};
            app.MaxareaofcellinpixelshoveroverformoredetailsTextArea = uitextarea(app.ThresholdTab);
            app.MaxareaofcellinpixelshoveroverformoredetailsTextArea.Tooltip = {'The higher the magnification'; ' the larger you want this number. '; '100000 is good for 60X images. '; '10000 is good for 20X images.'};
            app.MaxareaofcellinpixelshoveroverformoredetailsTextArea.Value = {'100000'};
            app.WatershedTab = uitab(app.TabGroup);
            app.WatershedTab.Title = 'Watershed';
            app.GenerateSeedButton = uibutton(app.WatershedTab, 'push');
            app.GenerateSeedButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSeedButtonPushed, true);
            app.GenerateSeedButton.Text = 'Generate Seed';
            app.SegmentRadius = uislider(app.WatershedTab);
            app.SegmentRadius.Limits = [0 1];
            app.SegmentRadius.MajorTicks = [];
            app.SegmentRadius.MajorTickLabels = {};
            app.SegmentRadius.MinorTicks = [];
            app.SegmentRadius.Value = 0.5;
            app.LevelLabel2_3 = uilabel(app.WatershedTab);
            app.LevelLabel2_3.Text = 'Size Of Seed';
            app.WatershedButton = uibutton(app.WatershedTab, 'push');
            app.WatershedButton.ButtonPushedFcn = createCallbackFcn(app, @WatershedButtonPushed, true);
            app.WatershedButton.Text = 'Watershed';
            app.LevelLabel2_36 = uilabel(app.WatershedTab);
            app.LevelLabel2_36.Text = 'Small';
            app.LevelLabel2_37 = uilabel(app.WatershedTab);
            app.LevelLabel2_37.Text = 'Large';
            app.LevelLabel2_38 = uilabel(app.WatershedTab);
            app.LevelLabel2_38.Text = 'Pre-processing';
            app.SegmentDropDown_2 = uidropdown(app.WatershedTab);
            app.SegmentDropDown_2.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.SegmentDropDown_2.Value = 'Channel 1';
            app.LevelLabel2_39 = uilabel(app.WatershedTab);
            app.LevelLabel2_39.Text = 'Watershed Channel';
            app.SegmentSigma_2 = uislider(app.WatershedTab);
            app.SegmentSigma_2.Limits = [0 10];
            app.SegmentSigma_2.MajorTicks = [];
            app.SegmentSigma_2.MajorTickLabels = {};
            app.SegmentSigma_2.MinorTicks = [];
            app.SegmentSigma_2.Value = 5;
            app.LevelLabel2_40 = uilabel(app.WatershedTab);
            app.LevelLabel2_40.Text = 'Gaussian Blur';
            app.LevelLabel2_41 = uilabel(app.WatershedTab);
            app.LevelLabel2_41.Text = 'Less';
            app.LevelLabel2_42 = uilabel(app.WatershedTab);
            app.LevelLabel2_42.Text = 'More';
            app.RefineTab = uitab(app.TabGroup);
            app.RefineTab.Title = 'Refine';
            app.View3DButton = uibutton(app.RefineTab, 'push');
            app.View3DButton.ButtonPushedFcn = createCallbackFcn(app, @View3DButtonPushed, true);
            app.View3DButton.Text = 'View 3D';
            app.ErodeMaskButton = uibutton(app.RefineTab, 'push');
            app.ErodeMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeMaskButtonPushed, true);
            app.ErodeMaskButton.Text = 'Erode Mask';
            app.DilateMaskButton = uibutton(app.RefineTab, 'push');
            app.DilateMaskButton.ButtonPushedFcn = createCallbackFcn(app, @DilateMaskButtonPushed, true);
            app.DilateMaskButton.Text = 'Dilate Mask';
            app.ApplyTransformButton = uibutton(app.RefineTab, 'push');
            app.ApplyTransformButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTransformButtonPushed, true);
            app.ApplyTransformButton.Text = 'Apply Transform';
            app.ErosionAmountSpinnerLabel = uilabel(app.RefineTab);
            app.ErosionAmountSpinnerLabel.Text = 'Erosion Amount';
            app.ErosionAmountSpinner = uispinner(app.RefineTab);
            app.ErosionAmountSpinner.Limits = [1 Inf];
            app.ErosionAmountSpinner.Value = 5;
            app.DilationAmountSpinnerLabel = uilabel(app.RefineTab);
            app.DilationAmountSpinnerLabel.Text = 'Dilation Amount';
            app.DilationAmountSpinner = uispinner(app.RefineTab);
            app.DilationAmountSpinner.Limits = [0 Inf];
            app.DilationAmountSpinner.Value = 5;
            app.LevelLabel2_32 = uilabel(app.RefineTab);
            app.LevelLabel2_32.Text = 'Remove Abbuting Cells With Distance Transform ';
            app.SegmentRadius_2 = uislider(app.RefineTab);
            app.SegmentRadius_2.Limits = [0 1];
            app.SegmentRadius_2.MajorTicks = [];
            app.SegmentRadius_2.MajorTickLabels = {};
            app.SegmentRadius_2.MinorTicks = [];
            app.SegmentRadius_2.Value = 0.5;
            app.LevelLabel2_33 = uilabel(app.RefineTab);
            app.LevelLabel2_33.Text = 'Cell Size';
            app.LevelLabel2_34 = uilabel(app.RefineTab);
            app.LevelLabel2_34.Text = 'Small';
            app.LevelLabel2_35 = uilabel(app.RefineTab);
            app.LevelLabel2_35.Text = 'Large';
            app.ApplyIn3DCheckBox = uicheckbox(app.RefineTab);
            app.ApplyIn3DCheckBox.Text = 'Apply In 3D';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Segmenter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegmenterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmenterUIFigure)
        end
    end
end